classdef Mortgage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MortgageCalculatorUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        deltaE                      matlab.ui.control.Slider
        SliderLabel                 matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        SigmoidPlotUIAxes           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MortgageCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {316, 316};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function MonthlyPaymentButtonPushed(app, event)
                       
            % Calculate the monthly payment
            load ExperimentalData.mat E
            load ExperimentalData.mat asig
            load ExperimentalData.mat ai


            p1 = plot(E,asig,'LineWidth',6);
            plot(E,ai,'LineWidth',2);
            p1.Color(4) = 0.5;
            xlabel('Energy (eV)')
            ylabel('$\alpha$ ','Interpreter','latex')
            set(gca,'FontSize',16)

            % Plot the principal and interest
            p1 = plot(app.SigmoidPlotUIAxes', E, asig, 'LineWidth', 6);
            p1.Color(4) = 0.5;
            plot(app.SigmoidPlotUIAxes',E,ai,'LineWidth',2);
            legend(app.SigmoidPlotUIAxes,{'Sig','Moid'},'Location','Best')
            xlim(app.SigmoidPlotUIAxes,'auto'); 
            ylim(app.SigmoidPlotUIAxes,'auto');

%             amount = app.LoanAmountEditField.Value;
%             rate = app.InterestRateEditField.Value/12/100;
%             nper = 12*app.LoanPeriodYearsEditField.Value;
%             payment = (amount*rate)/(1-(1+rate)^-nper);
%             app.MonthlyPaymentEditField.Value = payment;
%             
%             % pre allocating and initializing variables
%             interest = zeros(1,nper);
%             principal = zeros(1,nper);
%             balance = zeros (1,nper);
%             
%             balance(1) = amount;
% 
%             % ,(1:nper)
%             
%             % Calculate the principal and interest over time
%             for i = 1:nper
%                 interest(i)  = balance(i)*rate;
%                 principal(i) = payment - interest(i);
%                 balance(i+1) = balance(i) - principal(i);
%             end
%             
%             % Plot the principal and interest
%             plot(app.SigmoidPlotUIAxes,(1:nper)',principal, ...
%                 (1:nper)',interest);
%             legend(app.SigmoidPlotUIAxes,{'Principal','Interest'},'Location','Best')
%             xlim(app.SigmoidPlotUIAxes,[0 nper]); 
%             ylim(app.SigmoidPlotUIAxes,'auto');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MortgageCalculatorUIFigure = uifigure('Visible', 'off');
            app.MortgageCalculatorUIFigure.Name = 'Mortgage Calculator';
            app.MortgageCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MortgageCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SliderLabel = uilabel(app.LeftPanel);
            app.SliderLabel.Text = 'Slider';
            app.deltaE = uislider(app.LeftPanel);
            app.deltaE.Limits = [0 1];
            app.RightPanel = uipanel(app.GridLayout);
            app.SigmoidPlotUIAxes = uiaxes(app.RightPanel);
            title(app.SigmoidPlotUIAxes, 'Principal and Interest', 'Interpreter', 'latex')
            xlabel(app.SigmoidPlotUIAxes, 'Time (Months)', 'Interpreter', 'latex')
            ylabel(app.SigmoidPlotUIAxes, 'Amount', 'Interpreter', 'latex')
            % Show the figure after all components are created
            app.MortgageCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mortgage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MortgageCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MortgageCalculatorUIFigure)
        end
    end
end