classdef SigmoidApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SigmoidPlotUIFigure  matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        BandGapCalculation   matlab.ui.control.NumericEditField
        eVEditFieldLabel     matlab.ui.control.Label
        BandGapText          matlab.ui.control.TextArea
        deltaEnumber         matlab.ui.control.NumericEditField
        ValueEditFieldLabel  matlab.ui.control.Label
        deltaE               matlab.ui.control.Slider
        deltaELabel          matlab.ui.control.Label
        RightPanel           matlab.ui.container.Panel
        SigmoidPlotUIAxes    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function updatePlot(app)
                        
            dE = app.deltaE.Value;

            load ExperimentalData.mat amin
            load ExperimentalData.mat amax
            load ExperimentalData.mat E0
            load ExperimentalData.mat E
            load ExperimentalData.mat ai

            asig = (amax + (amin - amax)./(1+exp((E-E0)./dE)));
            asig = asig';

%             p1 = plot(E,asig,'LineWidth',6);
%             plot(E,ai,'LineWidth',2);
%             p1.Color(4) = 0.5;
%             xlabel('Energy (eV)')
%             ylabel('$\alpha$ ','Interpreter','latex')
%             set(gca,'FontSize',16)

            % Plot the principal and interest
            cla(app.SigmoidPlotUIAxes)
            p1 = plot(app.SigmoidPlotUIAxes', E, asig, 'LineWidth', 6);
            p1.Color(4) = 0.5;
            hold(app.SigmoidPlotUIAxes, 'on')
            plot(app.SigmoidPlotUIAxes',E,ai,'LineWidth',2);
            legend(app.SigmoidPlotUIAxes,{'Sig','Moid'},'Location','Best')
            xlim(app.SigmoidPlotUIAxes,'auto'); 
            ylim(app.SigmoidPlotUIAxes,'auto');

        end
        
        function startupFcn(app)
            updatePlot(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            runStartupFcn(app, @startupFcn)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SigmoidPlotUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {316, 316};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: deltaE, deltaEnumber
        function deltaEValueChanged(app, event)
            dE = app.deltaE.Value;

            load ExperimentalData.mat amin
            load ExperimentalData.mat amax
            load ExperimentalData.mat E0
            load ExperimentalData.mat E
            load ExperimentalData.mat ai

            asig = (amax + (amin - amax)./(1+exp((E-E0)./dE)));
            asig = asig';

%             p1 = plot(E,asig,'LineWidth',6);
%             plot(E,ai,'LineWidth',2);
%             p1.Color(4) = 0.5;
%             xlabel('Energy (eV)')
%             ylabel('$\alpha$ ','Interpreter','latex')
%             set(gca,'FontSize',16)

            % Plot the principal and interest
            cla(app.SigmoidPlotUIAxes)
            p1 = plot(app.SigmoidPlotUIAxes', E, asig, 'LineWidth', 6);
            p1.Color(4) = 0.5;
            hold(app.SigmoidPlotUIAxes, 'on')
            plot(app.SigmoidPlotUIAxes',E,ai,'LineWidth',2);
            legend(app.SigmoidPlotUIAxes,{'Sig','Moid'},'Location','Best')
            xlim(app.SigmoidPlotUIAxes,'auto'); 
            ylim(app.SigmoidPlotUIAxes,'auto');

            app.deltaEnumber.Value = app.deltaE.Value;

            nboltzdir = 0.3;
            nboltzindir = 4.3;
            Eboltz = E0 - (nboltzdir*dE);

            app.BandGapCalculation.Value = Eboltz;

%             amount = app.LoanAmountEditField.Value;
%             rate = app.InterestRateEditField.Value/12/100;
%             nper = 12*app.LoanPeriodYearsEditField.Value;
%             payment = (amount*rate)/(1-(1+rate)^-nper);
%             app.MonthlyPaymentEditField.Value = payment;
%             
%             % pre allocating and initializing variables
%             interest = zeros(1,nper);
%             principal = zeros(1,nper);
%             balance = zeros (1,nper);
%             
%             balance(1) = amount;
% 
%             % ,(1:nper)
%             
%             % Calculate the principal and interest over time
%             for i = 1:nper
%                 interest(i)  = balance(i)*rate;
%                 principal(i) = payment - interest(i);
%                 balance(i+1) = balance(i) - principal(i);
%             end
%             
%             % Plot the principal and interest
%             plot(app.SigmoidPlotUIAxes,(1:nper)',principal, ...
%                 (1:nper)',interest);
%             legend(app.SigmoidPlotUIAxes,{'Principal','Interest'},'Location','Best')
%             xlim(app.SigmoidPlotUIAxes,[0 nper]); 
%             ylim(app.SigmoidPlotUIAxes,'auto');

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SigmoidPlotUIFigure = uifigure('Visible', 'off');
            app.SigmoidPlotUIFigure.Name = 'Mortgage Calculator';
            app.SigmoidPlotUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SigmoidPlotUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.deltaELabel = uilabel(app.LeftPanel);
            app.deltaELabel.Text = 'dE';
            app.deltaE = uislider(app.LeftPanel);
            app.deltaE.Limits = [0 0.2];
            app.deltaE.ValueChangedFcn = createCallbackFcn(app, @deltaEValueChanged, true);
            app.deltaE.Value = 0.1;
            app.ValueEditFieldLabel = uilabel(app.LeftPanel);
            app.ValueEditFieldLabel.Text = 'Value';
            app.deltaEnumber = uieditfield(app.LeftPanel, 'numeric');
            app.deltaEnumber.ValueChangedFcn = createCallbackFcn(app, @deltaEValueChanged, true);
            app.BandGapText = uitextarea(app.LeftPanel);
            app.BandGapText.Value = {'Band Gap'};
            app.eVEditFieldLabel = uilabel(app.LeftPanel);
            app.eVEditFieldLabel.Text = 'eV';
            app.BandGapCalculation = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.SigmoidPlotUIAxes = uiaxes(app.RightPanel);
            title(app.SigmoidPlotUIAxes, 'Sigmoid Function', 'Interpreter', 'latex')
            xlabel(app.SigmoidPlotUIAxes, 'Energy (eV)', 'Interpreter', 'latex')
            ylabel(app.SigmoidPlotUIAxes, 'Absorption Coefficient', 'Interpreter', 'latex')
            % Show the figure after all components are created
            app.SigmoidPlotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SigmoidApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SigmoidPlotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SigmoidPlotUIFigure)
        end
    end
end